classdef ErrP_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ErrPAnalysisUIFigure       matlab.ui.Figure
        PreprocessButton           matlab.ui.control.Button
        NumberoftrialsLabel        matlab.ui.control.Label
        FeatureExtractionPanel     matlab.ui.container.Panel
        ClearButton                matlab.ui.control.Button
        SelectfeaturesCheckBox     matlab.ui.control.CheckBox
        AugmentCheckBox            matlab.ui.control.CheckBox
        NormalizefeaturesCheckBox  matlab.ui.control.CheckBox
        sLabel                     matlab.ui.control.Label
        EditFieldEnd               matlab.ui.control.NumericEditField
        endLabel                   matlab.ui.control.Label
        EditFieldStart             matlab.ui.control.NumericEditField
        TimewindowstartLabel       matlab.ui.control.Label
        DropDownNcomp              matlab.ui.control.DropDown
        NumberofspatialprojectionsLabel  matlab.ui.control.Label
        EditFieldNfeat             matlab.ui.control.NumericEditField
        NumberofFeaturesLabel      matlab.ui.control.Label
        PlotFeaturesButton         matlab.ui.control.Button
        FeaturesListBox            matlab.ui.control.ListBox
        FeaturesListBoxLabel       matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        FCBTab                     matlab.ui.container.Tab
        UIAxesFCB                  matlab.ui.control.UIAxes
        xDAWNTab                   matlab.ui.container.Tab
        UIAxesXDAWN                matlab.ui.control.UIAxes
        OverlapTab                 matlab.ui.container.Tab
        UIAxesOverlap              matlab.ui.control.UIAxes
        FCzCzTab                   matlab.ui.container.Tab
        UIAxesFCzCz                matlab.ui.control.UIAxes
        TimepointsTab              matlab.ui.container.Tab
        UIAxesAllChannels          matlab.ui.control.UIAxes
        Hyperlink                  matlab.ui.control.Hyperlink
        CorrectLabel               matlab.ui.control.Label
        ErrorLabel                 matlab.ui.control.Label
        TabGroup3                  matlab.ui.container.TabGroup
        AverageTab                 matlab.ui.container.Tab
        UIAxesAverage              matlab.ui.control.UIAxes
        errorcorrectTab            matlab.ui.container.Tab
        UIAxesDifference           matlab.ui.control.UIAxes
        RsquareTab                 matlab.ui.container.Tab
        UIAxesRsquare              matlab.ui.control.UIAxes
        AUCTab                     matlab.ui.container.Tab
        UIAxesAUC                  matlab.ui.control.UIAxes
        SNRTab                     matlab.ui.container.Tab
        UIAxesSNR                  matlab.ui.control.UIAxes
        SubjectDropDown            matlab.ui.control.DropDown
        SubjectDropDownLabel       matlab.ui.control.Label
        ExportButton               matlab.ui.control.Button
        DescriptionTextArea        matlab.ui.control.TextArea
        DescriptionLabel           matlab.ui.control.Label
        FC2CheckBox                matlab.ui.control.CheckBox
        FC1CheckBox                matlab.ui.control.CheckBox
        FCzCheckBox                matlab.ui.control.CheckBox
        FpzCheckBox                matlab.ui.control.CheckBox
        OzCheckBox                 matlab.ui.control.CheckBox
        POzCheckBox                matlab.ui.control.CheckBox
        PO8CheckBox                matlab.ui.control.CheckBox
        PO7CheckBox                matlab.ui.control.CheckBox
        P4CheckBox                 matlab.ui.control.CheckBox
        P3CheckBox                 matlab.ui.control.CheckBox
        PzCheckBox                 matlab.ui.control.CheckBox
        CPzCheckBox                matlab.ui.control.CheckBox
        C4CheckBox                 matlab.ui.control.CheckBox
        C3CheckBox                 matlab.ui.control.CheckBox
        CzCheckBox                 matlab.ui.control.CheckBox
        FzCheckBox                 matlab.ui.control.CheckBox
        PlotChannelsButton         matlab.ui.control.Button
        FilenameEditField          matlab.ui.control.EditField
        FilenameEditFieldLabel     matlab.ui.control.Label
        DatasetDropDown            matlab.ui.control.DropDown
        DatasetDropDownLabel       matlab.ui.control.Label
        ClassificationPanel        matlab.ui.container.Panel
        ClearTableButton           matlab.ui.control.Button
        SVMrbfCheckBox             matlab.ui.control.CheckBox
        SVMCheckBox                matlab.ui.control.CheckBox
        sLDACheckBox               matlab.ui.control.CheckBox
        BayesCheckBox              matlab.ui.control.CheckBox
        RunButton                  matlab.ui.control.Button
        TabGroup2                  matlab.ui.container.TabGroup
        BayesTab                   matlab.ui.container.Tab
        UITable_Bayes              matlab.ui.control.Table
        sLDATab                    matlab.ui.container.Tab
        UITable_sLDA               matlab.ui.control.Table
        SVMTab                     matlab.ui.container.Tab
        UITable_SVM                matlab.ui.control.Table
        SVMrbfTab                  matlab.ui.container.Tab
        UITable_SVMrbf             matlab.ui.control.Table
    end

    
    properties (Access = private)
        DialogApp;
        nofchannels = 12; % 
        datasetName="LSC Speller";
        featuresList = ["FCB", "xDAWN", "Overlapped window averages", "", "FCz and Cz", "", "Time points (All channels)"];
        datasetNo = 3;
        inputnumber=0;
        file;
        path;
        subjectid=1; % default 0 - All subjects
        channels = ones(1,12);
        Xtrain, Ytrain, Xtest, Ytest, XtrainAll, YtrainAll, N_tst;
        fs=256; 
        sp = 1; 
        nf = 200; %100  % number of features
        scomp = 2; % number of spatial components
        pcomp = 2; % number of components PCA
        trialstart = 0;  
        trialend= 1;
        fdtime = 1;
        tonset =0.2;
        selwin = [0.2 0.7];
        winstep = 0.05; wsize = 0.1;
        test_exist = 1;
        clsfrBayes = 1; clsfrsLDA = 1; clsfrSVM = 1; clsfrSVMrbf = 1;
        acrosstime=1;
        totalsubjects = 0;
        traintest=1;
        total_Bayes = [0 0 0 0 0];
        total_sLDA = [0 0 0 0 0];
        total_SVM = [0 0 0 0 0];
        total_SVMrbf = [0 0 0 0 0];
        avg=0;
        car = 0; % common average reference
        %bandpass filter
        lf = 1; % low cutoff frequency
        hf = 10; % high cutoff frequency
        fczcz = [];
        channelnames = ["Fz", "Cz", "C3", "C4", "CPz", "Pz", "P3", "P4", "PO7", "PO8", "POz", "Oz" ];
        featset = 1;
        featsel = 0; % if there is feature selection (1) or not (0)
        featsel_method = 1; % feature selection method 1->rsquare 2->PCA
        normalize = 1;
        downsamp=0; % downsampling method: 0 -> no downsampling  1-> matlab downsample function (stepping the window size) 2-> taking average of the window
        winselect = 0;
        augment = 0;
        noftrials = {'Error',0,'Correct',0};
        nofpeaks = 4;
        peakselmethod=2;
        arorder = 7;
        
    end
    
    methods (Access = public)
    
        function initializeParams(app)
            app.FeaturesListBox.Value = app.featuresList(app.featset);
            FeaturesListBoxValueChanged(app);
           app.EditFieldNfeat.Value = app.nf;
           app.DropDownNcomp.Value = num2str(app.scomp);
           app.EditFieldStart.Value = app.trialstart;
           app.EditFieldEnd.Value = app.trialend; 
           app.NormalizefeaturesCheckBox.Value = app.normalize;
           app.AugmentCheckBox.Value = app.augment;
          
        end

        function printAllpreparams(app)
            fprintf(" CAR: %d, Bandpass filter %.2f - %.2f Hz, Downsampling: %d, Feature normalization: %d, Augmentation: %d ... \n",app.car,app.lf,app.hf,app.downsamp, app.normalize, app.augment);   
        end

        function updateparams(app, car, lf, hf, downsamp) 
            % Store inputs as properties
            app.car = car;
            app.lf = lf;
            app.hf = hf;
            app.downsamp = downsamp;
            printAllpreparams(app);
            % Re-enable the Plot Parameters button
            app.PreprocessButton.Enable = 'on';
        end
        
        function plotchndata(app) 
             
            if app.subjectid == 0
                
                zerrors = app.XtrainAll(:,:,app.YtrainAll==1);
                znonerrors = app.XtrainAll(:,:,app.YtrainAll==2);
            else
                zerrors = app.Xtrain(:,:,app.Ytrain==1);
                znonerrors = app.Xtrain(:,:,app.Ytrain==2);
            end
            
            errdata= mean(zerrors,3); % average of error trials per channel
            corrdata= mean(znonerrors,3); %average of correct trials per channel
            dif_ec = errdata - corrdata;  % difference (error - correct)
            tpoints=size(errdata,2);
            duration=tpoints/app.fs;

            % SNR
            zerrors_sel = zerrors(:,app.fs*(0.2+app.selwin(1))+1:app.fs*(0.2+app.selwin(2)),:);
            znonerrors_sel = znonerrors(:,app.fs*(0.2+app.selwin(1))+1:app.fs*(0.2+app.selwin(2)),:);
             for i=1:app.nofchannels
                  x_er = reshape(zerrors_sel(i,:,:),[size(zerrors_sel,2) size(zerrors_sel,3)]);
                  x_cor = reshape(znonerrors_sel(i,:,:),[size(znonerrors_sel,2) size(znonerrors_sel,3)]);
                  SNR_er(i)=gp_SNR_alltrials(x_er',3);
                  SNR_cor(i)=gp_SNR_alltrials(x_cor',3);
             end
            
                 t = -0.2+1/app.fs : 1/app.fs : -0.2+size(errdata,2)/app.fs;
           % t = 1/app.fs : 1/app.fs : size(errdata,2)/app.fs;
            idx=find(app.channels==1);
            %disp(idx)
            disp(['Plotting channels (', num2str(idx),')']);
            if isempty(idx)
               cla(app.UIAxesAverage);
            else
                % plot average 
                p1=plot(app.UIAxesAverage,t,errdata(app.channels==1,:),'Color','r');
                hold(app.UIAxesAverage, 'on');
                p2=plot(app.UIAxesAverage,t,corrdata(app.channels==1,:),'Color','b');
                hold(app.UIAxesAverage, 'off');
                h1= [p1(1); p2(1)];
                legend(app.UIAxesAverage,h1,{'error','correct'})
                
                %plot difference
                p3=plot(app.UIAxesDifference,t,dif_ec(app.channels==1,:), "Color",'black');
                legend(app.UIAxesDifference, p3,'error-correct')
              
                % plot SNR 
                 plot(app.UIAxesSNR,1:app.nofchannels,SNR_er,"Color",'r');
                 hold(app.UIAxesSNR, 'on');
                 plot(app.UIAxesSNR,1:app.nofchannels,SNR_cor,"Color",'b');
                 hold(app.UIAxesSNR, 'off');
                 legend(app.UIAxesSNR, {'error','correct'})
                                
            end            
           
        end


        function plotrsquare(app)
            if app.subjectid == 0
                train_matrix = app.XtrainAll(:,1:app.fs*(app.tonset+app.trialend),:);
                labels= app.YtrainAll;    
            else
                train_matrix = app.Xtrain(:,1:app.fs*(app.tonset+app.trialend),:);
                labels= app.Ytrain; 
            end
            
            for ch = 1 : size(train_matrix,1)
                for tpoint = 1:size(train_matrix,2)
                    rsq_z(tpoint,ch) = rsquare(train_matrix(ch, tpoint, (labels==1)),train_matrix(ch, tpoint, (labels==2)));
                end
            end
           %  t = [1/app.fs : 1/app.fs : size(train_matrix,2)/app.fs];
            t = -0.2+1/app.fs : 1/app.fs : -0.2+size(train_matrix,2)/app.fs;
            data2plot=rsq_z';
            data2plot=cat(2, data2plot, zeros(size(data2plot, 1), 1));
            data2plot=cat(1, data2plot, zeros(1, size(data2plot, 2)));
            tData=t;
            tData(end+1) = tData(end) + diff(tData(end-1:end));
            Ncanais=size(rsq_z,2);
            surf(app.UIAxesRsquare, tData, [1:Ncanais + 1], data2plot,'EdgeColor','none')
            axis(app.UIAxesRsquare, 'tight');
            view(app.UIAxesRsquare, 2);
            colormap(app.UIAxesRsquare, jet); 
            xlabel(app.UIAxesRsquare, 'Time (s)');
            ylabel(app.UIAxesRsquare, 'channels'); 
            colorbar(app.UIAxesRsquare);
                        
        end
        
        function plotacrosstime(app)
            if app.subjectid == 0
                ytrain = app.XtrainAll;
                clabel= app.YtrainAll;    
            else
                ytrain = app.Xtrain;
                clabel= app.Ytrain; 
            end
            y_train = permute(ytrain, [3 1 2]);
            [perf, result, dtime] = classifyacrosstime(y_train,clabel, app.fs, app.trialstart-0.2, app.trialend,10,10);
                   
             p4=plot(app.UIAxesAUC,dtime, perf);
             hold(app.UIAxesAUC, 'on');
             p4=plot(app.UIAxesAUC,dtime,0.5*ones(size(dtime)),'-.','Color','black');
             hold(app.UIAxesAUC, 'off');
              legend(app.UIAxesAUC,{'classification acc.','chance level'})
             ylim(app.UIAxesAUC,[0 1])
             xlabel(app.UIAxesAUC,'Time'), ylabel(app.UIAxesAUC,'AUC')
              grid(app.UIAxesAUC, 'on');
        end


        function folddata(app,noffold)
            trno=round((length(app.Ytrain)/noffold));
            rest= length(app.Ytrain)-trno*noffold;
            tst= ones(noffold,1)*trno;
            tst(noffold)=tst(noffold)+rest;
            app.N_tst=tst;    
        end
        
        function Xdownsampled = downsampleData(app, X, fs, new_fs, dsmethod)
            if dsmethod==1
                Xdownsampled = downsample(X,fs/new_fs);
            elseif dsmethod==2
                i=1;
                ws = fs/new_fs;
                for tw=1:ws:size(X,1)-1
                    Xdownsampled(i,:,:) = mean(X(tw:tw+ws-1, :, :),1); 
                    i=i+1;  
                end  
            end
                     
        end
        
        function loadSubjectData(app, id)
            if id == 0
                gettotalsubjects(app);
            else  
                datadir=  strcat('D:\ErrPDatasets\',app.datasetName);               
                if app.datasetName == "BNCI Moving Cursor"
                   app.trialstart=0;
                   app.trialend=1;
                   app.fs=512; 
                   app.nofchannels = 64;
                   app.winstep= 0.05;
                   app.wsize = 0.1;
                   app.fdtime=1;
                   app.test_exist = 1;
                   if app.downsamp==0
                        [app.Xtrain, app.Ytrain, app.Xtest, app.Ytest, app.fczcz, app.channelnames] =  fetchErrP1_BNCI(datadir, id, app.car, app.lf, app.hf, app.fs, app.trialstart, app.trialend);
                   else
                        [Xtemp, app.Ytrain, X2temp, app.Ytest, app.fczcz, app.channelnames] =  fetchErrP1_BNCI(datadir, id, app.car, app.lf, app.hf, app.fs, app.trialstart, app.trialend);
                        Xtemp = permute(Xtemp,[2 3 1]);
                        X2temp = permute(X2temp,[2 3 1]);
                        app.Xtrain = downsampleData(app, Xtemp, app.fs, app.fs/8, app.downsamp);
                        app.Xtest = downsampleData(app, X2temp, app.fs, app.fs/8, app.downsamp);
                        app.Xtrain = permute(app.Xtrain,[3 1 2]);
                        app.Xtest = permute(app.Xtest,[3 1 2]);
                        app.fs=app.fs/8;
                   end
                elseif app.datasetName == "Kaggle P300 Speller" 
                   app.traintest=3;
                   app.trialstart=0;
                   app.trialend=1.3; 
                   app.winstep= 0.05;
                   app.wsize = 0.1;
                   app.fs= 200;
                   app.nofchannels = 56; 
                   app.fdtime=1.3;
                   if app.downsamp==0
                         [app.Xtrain, app.Ytrain, app.Xtest, app.Ytest, app.fczcz, app.channelnames] =  fetchErrP2_Kaggle(datadir, id, app.car, app.lf, app.hf, app.fs, app.trialstart, app.trialend);
                   else
                        [Xtemp, app.Ytrain, X2temp, app.Ytest, app.fczcz, app.channelnames] =  fetchErrP2_Kaggle(datadir, id, app.car, app.lf, app.hf, app.fs, app.trialstart, app.trialend);
                        Xtemp = permute(Xtemp,[2 3 1]);
                        X2temp = permute(X2temp,[2 3 1]);
                        app.Xtrain = downsampleData(app, Xtemp, app.fs, app.fs/2, app.downsamp);
                        app.Xtest = downsampleData(app, X2temp, app.fs, app.fs/2, app.downsamp);
                        app.Xtrain = permute(app.Xtrain,[3 1 2]);
                        app.Xtest = permute(app.Xtest,[3 1 2]);
                        app.fs=app.fs/2;
                   end
                elseif app.datasetName == "LSC Speller"
                   app.fs= 256;
                   app.nofchannels = 12;
                   app.trialstart=0;
                   app.trialend=1; 
                   app.winstep= 0.05;
                   app.wsize = 0.1;
                   app.fdtime=1;
                   app.test_exist = 1;
                    if app.downsamp==0
                           [app.Xtrain, app.Ytrain, app.Xtest, app.Ytest, app.fczcz, app.channelnames] = fetchErrP3_LSC(datadir, id, app.car, app.lf, app.hf,  app.fs, app.trialstart, app.trialend);
                    else
                           [Xtemp, app.Ytrain, X2temp, app.Ytest, app.fczcz, app.channelnames] =  fetchErrP3_LSC(datadir, id, app.car, app.lf, app.hf, app.fs, app.trialstart, app.trialend);
                        Xtemp = permute(Xtemp,[2 3 1]);
                        X2temp = permute(X2temp,[2 3 1]);
                        app.Xtrain = downsampleData(app, Xtemp, app.fs, app.fs/4, app.downsamp);
                        app.Xtest = downsampleData(app, X2temp, app.fs, app.fs/4, app.downsamp);
                        app.Xtrain = permute(app.Xtrain,[3 1 2]);
                        app.Xtest = permute(app.Xtest,[3 1 2]);
                        app.fs=app.fs/4;
                    end
                   elseif app.datasetName == "HRI (cursor)"
                    expno=1;
                    app.fs= 256;
                    app.trialstart= 0; 
                    app.trialend= 1.2;
                    app.winstep= 0.05;
                    app.wsize = 0.1;
                    app.fdtime=1.2;
                    app.nofchannels = 27;
                    if app.downsamp==0
                       [app.Xtrain, app.Ytrain, app.N_tst,app.fczcz, app.channelnames] = fetchErrP4_HRI(datadir, id, app.car, app.lf, app.hf, expno, app.fs, app.trialstart, app.trialend);
                    else
                        [Xtemp, app.Ytrain, app.N_tst, app.fczcz, app.channelnames] =  fetchErrP4_HRI(datadir, id, app.car, app.lf, app.hf, expno, app.fs, app.trialstart, app.trialend);
                        Xtemp = permute(Xtemp,[2 3 1]);
                        app.Xtrain = downsampleData(app, Xtemp, app.fs, app.fs/4, app.downsamp);
                        app.Xtrain = permute(app.Xtrain,[3 1 2]);
                        app.fs=app.fs/4;
                    end
                elseif app.datasetName == "HRI (robot)"
                    expno=2;
                    app.fs= 256;
                    app.trialstart= 0; 
                    app.trialend= 1.2; 
                    app.winstep= 0.05;
                    app.wsize = 0.1;
                    app.fdtime=1.2;
                    app.nofchannels = 27;
                    if app.downsamp==0
                       [app.Xtrain, app.Ytrain, app.N_tst,app.fczcz, app.channelnames] = fetchErrP4_HRI(datadir, id, app.car, app.lf, app.hf, expno, app.fs, app.trialstart, app.trialend);
                    else
                        [Xtemp, app.Ytrain, app.N_tst, app.fczcz, app.channelnames] =  fetchErrP4_HRI(datadir, id, app.car, app.lf, app.hf, expno, app.fs, app.trialstart, app.trialend);
                        Xtemp = permute(Xtemp,[2 3 1]);
                        app.Xtrain = downsampleData(app, Xtemp, app.fs, app.fs/4, app.downsamp);
                        app.Xtrain = permute(app.Xtrain,[3 1 2]);
                        app.fs=app.fs/4;
                    end
                elseif app.datasetName == "Coadaptation"
                    app.fs= 256;
                    app.trialstart= 0; 
                    app.trialend= 1;    
                    app.winstep= 0.05;
                    app.wsize = 0.1;
                    app.fdtime=1;
                    app.nofchannels = 27;
                    app.test_exist = 1;
                    if app.downsamp==0
                        [app.Xtrain, app.Ytrain, app.Xtest, app.Ytest, app.fczcz, app.channelnames] = fetchErrP5_Coadaptation(datadir, id, app.car, app.lf, app.hf, app.fs,app.trialstart, app.trialend);
                    else
                          [Xtemp, app.Ytrain, X2temp, app.Ytest, app.fczcz, app.channelnames] = fetchErrP5_Coadaptation(datadir, id, app.car, app.lf, app.hf, app.fs,app.trialstart, app.trialend);
                        Xtemp = permute(Xtemp,[2 3 1]);
                        X2temp = permute(X2temp,[2 3 1]);
                        app.Xtrain = downsampleData(app, Xtemp, app.fs, app.fs/4, app.downsamp);
                        app.Xtest = downsampleData(app, X2temp, app.fs, app.fs/4, app.downsamp);
                        app.Xtrain = permute(app.Xtrain,[3 1 2]);
                        app.Xtest = permute(app.Xtest,[3 1 2]);
                        app.fs=app.fs/4;
                    end 
                    if app.test_exist==0
                        folddata(app,10);
                    end
                elseif app.datasetName == "Gaming Agent"
                    app.fs= 250;
                    app.trialstart=0;
                    app.trialend=2;  
                    app.winstep= 0.05;
                    app.wsize = 0.1;
                    app.fdtime=2;
                    app.nofchannels = 8;
                    if app.downsamp==0
                        [app.Xtrain, app.Ytrain, app.N_tst, app.fczcz, app.channelnames] = fetchErrP6_GameAgent(datadir, id, app.car, app.lf, app.hf, app.fs,app.trialstart, app.trialend);
                    else
                        [Xtemp, app.Ytrain, app.N_tst, app.fczcz, app.channelnames] = fetchErrP6_GameAgent(datadir, id, app.car, app.lf, app.hf, app.fs,app.trialstart, app.trialend);
                        Xtemp = permute(Xtemp,[2 3 1]);
                        app.Xtrain = downsampleData(app, Xtemp, app.fs, app.fs/4, app.downsamp);
                        app.Xtrain = permute(app.Xtrain,[3 1 2]);
                        app.fs=app.fs/4;
                    end
                    folddata(app,10);   
                elseif app.datasetName == "Gaze Speller"
                    app.fs= 256;
                    app.trialstart=0;
                    app.trialend=0.5;  
                    app.winstep= 0.05;
                    app.wsize = 0.1;
                    app.fdtime = 0.5;
                    app.nofchannels = 61;
                    if app.downsamp==0
                        [app.Xtrain, app.Ytrain, app.N_tst, app.fczcz, app.channelnames] = fetchErrP7_GazeSpeller(datadir, id, app.car, app.lf, app.hf, app.fs,app.trialstart, app.trialend);
                    else
                        [Xtemp, app.Ytrain, app.N_tst, app.fczcz, app.channelnames] = fetchErrP7_GazeSpeller(datadir, id, app.car, app.lf, app.hf, app.fs,app.trialstart, app.trialend);
                        Xtemp = permute(Xtemp,[2 3 1]);
                        app.Xtrain = downsampleData(app, Xtemp, app.fs, app.fs/2, app.downsamp);
                        app.Xtrain = permute(app.Xtrain,[3 1 2]);
                        app.fs=app.fs/2;
                    end
                   folddata(app,10);
                elseif app.datasetName == "Tactile Feedback (V)"
                    app.fs= 256;
                    app.trialstart=0; 
                    app.trialend=1;   
                    app.winstep= 0.05;
                    app.wsize = 0.1;
                    app.fdtime=1;
                    app.nofchannels = 16;
                    if app.downsamp==0
                        [app.Xtrain, app.Ytrain, app.N_tst, app.fczcz, app.channelnames] = fetchErrP8_Tactile(datadir, id, app.car, app.lf, app.hf, app.fs,app.trialstart, app.trialend);
                    else
                        [Xtemp, app.Ytrain, app.N_tst, app.fczcz, app.channelnames] = fetchErrP8_Tactile(datadir, id, app.car, app.lf, app.hf, app.fs,app.trialstart, app.trialend);
                        Xtemp = permute(Xtemp,[2 3 1]);
                        app.Xtrain = downsampleData(app, Xtemp, app.fs, app.fs/4, app.downsamp);
                        app.Xtrain = permute(app.Xtrain,[3 1 2]);
                        app.fs=app.fs/4;
                    end
                   folddata(app,10);
                elseif app.datasetName == "Tactile Feedback (VT)"
                    app.fs= 256;
                    app.trialstart = 0; 
                    app.trialend = 1;   
                    app.winstep = 0.05;
                    app.wsize = 0.1;
                    app.fdtime = 1;
                    app.nofchannels = 16;
                    if app.downsamp==0
                         [app.Xtrain, app.Ytrain, app.N_tst, app.fczcz, app.channelnames] = fetchErrP8_Tactile(datadir, id, app.car, app.lf, app.hf, app.fs,app.trialstart, app.trialend);
                    else
                        [Xtemp, app.Ytrain, app.N_tst, app.fczcz, app.channelnames] = fetchErrP8_Tactile(datadir, id, app.car, app.lf, app.hf, app.fs,app.trialstart, app.trialend);
                        Xtemp = permute(Xtemp,[2 3 1]);
                        app.Xtrain = downsampleData(app, Xtemp, app.fs, app.fs/4, app.downsamp);
                        app.Xtrain = permute(app.Xtrain,[3 1 2]);
                        app.fs=app.fs/4;
                    end
                   folddata(app,10);
                elseif app.datasetName == "Virtual Cursor"
                    app.fs= 256;
                    app.trialstart=0; 
                    app.trialend=1;   
                    app.winstep= 0.05;
                    app.wsize = 0.1;
                    app.fdtime=1;
                    app.nofchannels = 16;
                   if app.downsamp==0
                        [app.Xtrain, app.Ytrain, app.N_tst,  app.fczcz, app.channelnames] = fetchErrP9_VirtualCursor(datadir, id, app.car, app.lf, app.hf, app.fs,app.trialstart, app.trialend);
                    else
                        [Xtemp, app.Ytrain, app.N_tst,  app.fczcz, app.channelnames] = fetchErrP9_VirtualCursor(datadir, id, app.car, app.lf, app.hf, app.fs,app.trialstart, app.trialend);
                        Xtemp = permute(Xtemp,[2 3 1]);
                        app.Xtrain = downsampleData(app, Xtemp, app.fs, app.fs/4, app.downsamp);
                        app.Xtrain = permute(app.Xtrain,[3 1 2]);
                        app.fs=app.fs/4;
                   end
                   folddata(app,10);
                
                end   
                
            end
           fprintf("Epochs are extracted as %d channels x %d time points x %d trials..\n",size(app.Xtrain,1),size(app.Xtrain,2),size(app.Xtrain,3));   
        end
        
        
        function gettotalsubjects(app)
           if app.datasetName == "BNCI Moving Cursor"
                app.totalsubjects = 6;
            elseif app.datasetName == "Kaggle P300 Speller"                
                app.totalsubjects = 26;  
            elseif app.datasetName == "LSC Speller"
                app.totalsubjects = 8;  
            elseif app.datasetName == "HRI (cursor)"
                app.totalsubjects = 11;             
            elseif app.datasetName == "HRI (robot)"
                app.totalsubjects = 11;
            elseif app.datasetName == "Coadaptation"
                app.totalsubjects = 13;
            elseif app.datasetName == "Gaming Agent"
                app.totalsubjects = 8;
            elseif app.datasetName == "Gaze Speller"
                app.totalsubjects = 10;
            elseif app.datasetName == "Tactile Feedback (V)"
                app.totalsubjects = 12;
            elseif app.datasetName == "Tactile Feedback (VT)"
                app.totalsubjects = 12;
            elseif app.datasetName == "Virtual Cursor"
                app.totalsubjects = 8;
            end 
        end
        
        function loadAllSubjectsData(app)
            gettotalsubjects(app);
            for i=1:app.totalsubjects
                loadSubjectData(app,i);
                % concatenate app.Xtrain and app.Ytrain of all subjects
                if i==1
                    app.XtrainAll = app.Xtrain;
                    app.YtrainAll = app.Ytrain;
                else
                    app.XtrainAll = cat(3, app.XtrainAll, app.Xtrain);
                    app.YtrainAll = [app.YtrainAll, app.Ytrain];
                end
                
            end
        end

        function [ytrain_cne, ytrain_cne_bf, ytest_cne, ytest_cne_bf] = extractepochs (app)
            % selecting time window
            if app.winselect==0
                t1= app.EditFieldStart.Value;
                t2= app.EditFieldEnd.Value;
            elseif app.winselect==1
                t1=app.selwin(1);
                t2=app.selwin(2);
            end
             ytest_cne=[]; ytest_cne_bf = [];
            fprintf("Time window: %.2f - %.2f \n",(t1),(t2))
            X1win = app.Xtrain(:,round((t1+0.2)*app.fs)+1:round((t2+0.2)*app.fs),:); 
            X1winbf = app.Xtrain(:,round((t1+0.1)*app.fs)+1:round((t2+0.1)*app.fs),:);  % time shifted 100 ms before
            fprintf("Training data: %d channels x %d time points x %d trials..\n",size(X1win,1),size(X1win,2),size(X1win,3));   
            if app.test_exist==1
               X2win = app.Xtest(:,round((t1+0.2)*app.fs)+1:round((t2+0.2)*app.fs),:); 
               X2winbf = app.Xtest(:,round((t1+0.1)*app.fs)+1:round((t2+0.1)*app.fs),:);
                 fprintf("Testing data: %d channels x %d time points x %d trials..\n",size(X2win,1),size(X2win,2),size(X2win,3));   
            end

             if app.featset==3  % overlapped time window averaging
                 i=1;     
                for tw=0:app.winstep:t2-t1-app.wsize % 0.001  % %%%%%%%%%%
                    twin =  floor(tw*app.fs)+1:floor((tw+app.wsize)*app.fs);
                    ytrain_cne(:,i,:) = mean(X1win(:, twin, :),2); 
                    ytrain_cne_bf(:,i,:) = mean(X1winbf(:, twin, :),2); 
                    if app.test_exist == 1
                    ytest_cne(:,i,:) = mean(X2win(:, twin, :),2);      
                    ytest_cne_bf(:,i,:) = mean(X2winbf(:, twin, :),2);      
                    end
                    i=i+1;
                end 
                fprintf("Averaging overlapped  time windows: %d channels x %d windows..\n",size(ytrain_cne,1), size(ytrain_cne,2)); 
            elseif app.featset==5  %  FCz and Cz channels
                    ytrain_cne = X1win(app.fczcz,:,:);
                    ytrain_cne_bf = X1winbf(app.fczcz,:,:);
                if app.test_exist==1
                    ytest_cne = X2win(app.fczcz,:,:);
                    ytest_cne_bf = X2winbf(app.fczcz,:,:);
                end  
             elseif app.featset==1  ||  app.featset==2   ||   app.featset==7  
                 ytrain_cne = X1win;
                 ytrain_cne_bf = X1winbf;
                  if app.test_exist==1
                     ytest_cne = X2win;
                     ytest_cne_bf = X2winbf;
                 end
             end

        end

        
        function KFoldCrossValid(app, p)
            printAllpreparams(app);
            fprintf("Sampling frequency: %d..\n",app.fs); 
            fprintf("Feature set: %d..\n",app.featset); 
            fprintf("Number of channels: %d..\n",app.nofchannels); 
            
            [ytrain_cne, ytrain_cne_bf, ytest_cne, ytest_cne_bf] = extractepochs (app);

            visual= app.Ytrain;           
            automaticBayes=[]; 
            automaticsLDA =[];
            automaticSVM =[]; 
            automaticSVMrbf =[]; 
            visLabel=[];
            
            if app.test_exist==0
                Fold = length(app.N_tst);        
                K=0;
                Indexarry(1,1)=K;
                for i=1:Fold
                    if Fold==1
                    K = K + app.N_tst(i,1)/2;
                    Indexarry(i+1,1)=K;
                    else
                    K = K + app.N_tst(i,1);
                    Indexarry(i+1,1)=K;
                    end
                end
                fprintf("%d - Fold Cross-Validation..\n",Fold); 
                fprintf("------------------------------------\n");
            else
                Fold=1; 
                fprintf("Testing online data..\n"); 
                fprintf("-------------------------- \n");
            end

            SubDataSet=[];   
            for i=1:length(visual)
                SubDataSet(i,:)=i;
            end

            for inc = 1:Fold 
               
                fprintf("Testing Fold %d..\n", inc);                 
                fprintf("----------------------\n"); 
                if app.test_exist == 0
                    LB=1+Indexarry(inc,1);
                    HB=Indexarry(inc+1,1);
                    SubDataSetAux = SubDataSet;
                    SubDataSetAux(LB:HB,:)=[];
                    TrnInd = hs_mat2vect(SubDataSetAux);
                else
                    TrnInd = hs_mat2vect(SubDataSet);     
                end
                
                TrnLabel = visual(TrnInd);
                [ind_1] = hs_IndexHipnog(TrnLabel,1,1);   
                [ind_2] = hs_IndexHipnog(TrnLabel,2,2);
                
                % ErrP
                ytarget_cn2=ytrain_cne(:,:,TrnInd);
                ytarget_cn2_bf=ytrain_cne_bf(:,:,TrnInd);

                if app.featset==1 % FCB filter
                 
                fprintf("%d FCB spatial projections..\n",app.scomp); 
                 [zspatialTRAIN_t, zspatialTRAIN_nt, Uall, ~, totalnumfeat ] =  fcb_feature_extraction(ytarget_cn2(:,:,ind_1),ytarget_cn2(:,:,ind_2), app.nofchannels, app.scomp, app.normalize);
                 [modelo_T, modelo_nonT, win, X, clabel, Point3] =  feature_select_train(zspatialTRAIN_t',zspatialTRAIN_nt', app.featsel, app.featsel_method, app.nf);
                 if app. augment ==1
                     [zspatialTRAIN_t_bf, zspatialTRAIN_nt_bf, Uallbf, ~, totalnumfeat_bf ]  =  fcb_feature_extraction(ytarget_cn2_bf(:,:,ind_1),ytarget_cn2_bf(:,:,ind_2), app.nofchannels,app.scomp,app.normalize);
                     [modelo_T_bf, modelo_nonT_bf, win_bf, X_bf, clabel_bf, Point3_bf] =  feature_select_train(zspatialTRAIN_t_bf',zspatialTRAIN_nt_bf', app.featsel, app.featsel_method, app.nf);
                 end
                
                elseif app.featset==2 % xdawn filter
                fprintf("%d xDAWN spatial projections..\n",app.scomp); 
                [xdawnspatialTRAIN_t, xdawnspatialTRAIN_nt, W, totalnumfeat]= xdawn_feature_extraction(ytarget_cn2,TrnLabel,app.scomp, app.normalize); 
                [modelo_T, modelo_nonT, win, X, clabel, Point3 ] =  feature_select_train(xdawnspatialTRAIN_t',xdawnspatialTRAIN_nt', app.featsel, app.featsel_method, app.nf);
                if app. augment ==1
                    [xdawnspatialTRAIN_t_bf, xdawnspatialTRAIN_nt_bf, W_bf, totalnumfeat_bf]= xdawn_feature_extraction(ytarget_cn2_bf, TrnLabel, app.scomp, app.normalize); 
                    [modelo_T_bf, modelo_nonT_bf, win_bf, X_bf, clabel_bf, Point3_bf] =  feature_select_train(xdawnspatialTRAIN_t_bf',xdawnspatialTRAIN_nt_bf', app.featsel, app.featsel_method, app.nf);
                end

                elseif  app.featset==3  ||     app.featset==5      ||    app.featset==7
                    fprintf("Temporal features of feature set %d from %d channels..\n",app.featset,size(ytarget_cn2,1)); 
                    [ztemporalTRAIN_t, ztemporalTRAIN_nt, totalnumfeat]= temporal_feature_extraction(ytarget_cn2, TrnLabel, app.normalize);
                    [modelo_T, modelo_nonT, win, X, clabel, Point3 ] =  feature_select_train(ztemporalTRAIN_t', ztemporalTRAIN_nt', app.featsel, app.featsel_method, app.nf);       
                    if app. augment ==1
                         [ztemporalTRAIN_t_bf, ztemporalTRAIN_nt_bf, totalnumfeat_bf]= temporal_feature_extraction(ytarget_cn2_bf, TrnLabel, app.normalize);
                         [modelo_T_bf, modelo_nonT_bf, win_bf, X_bf, clabel_bf, Point3_bf ] =  feature_select_train(ztemporalTRAIN_t_bf', ztemporalTRAIN_nt_bf', app.featsel, app.featsel_method, app.nf); 
                    end
                          
                end

                fprintf("Number of training samples: %d..\n",size(X,1)); 
                
                if  app. augment ==0
                       fprintf("%d of %d features used to train the classifier..\n",size(X,2),totalnumfeat); 
                elseif app. augment ==1    
                     fprintf("%d of %d features from original data and %d of %d features from augmented data used to train the classifiers..\n",size(X,2),totalnumfeat,size(X_bf,2),totalnumfeat_bf);
                end


                %% Test           

                if app.test_exist == 0
                    TstInd = SubDataSet(LB:HB,:);
                    TstLabel = visual(TstInd);    
                
                    [tstind_1] = hs_IndexHipnog(TstLabel,1,1);  
                    [tstind_2] = hs_IndexHipnog(TstLabel,2,2);
                    IndTest=[tstind_1 tstind_2]';
                    TstLabel1=TstLabel(IndTest);
                    % ytrain_cne -> n*t*k
                    ytarget_ide= permute(ytrain_cne, [3 2 1]); 
                    ytarget_ide_bf= permute(ytrain_cne_bf, [3 2 1]); 
                    % ytrain_ide -> k*t*n
                    ytarget_id2= ytarget_ide(TstInd,:,:);  
                    ytarget_id2_bf= ytarget_ide_bf(TstInd,:,:);  
                else
                    ytarget_id2=permute(ytest_cne, [3 2 1]);
                    ytarget_id2_bf=permute(ytest_cne_bf, [3 2 1]);
                    TstLabel1=app.Ytest;
                    IndTest= 1:size(ytarget_id2,1);
                end        
               
                 if app.featset==1  
                     ychannels = fcb_spatial_filter_projection(ytarget_id2(IndTest,:,:),Uall, app.scomp,  app.normalize);   
                     ychannelsel =  feature_select_test(ychannels',Point3,app.featsel);   
                     if app. augment ==1
                         ychannels_bf = fcb_spatial_filter_projection(ytarget_id2_bf(IndTest,:,:),Uallbf, app.scomp,  app.normalize);   
                         ychannelsel_bf =  feature_select_test(ychannels_bf',Point3_bf,app.featsel);
                     end
                 elseif app.featset==2
                        ychannels = xdawn_spatial_filter_projection(ytarget_id2(IndTest,:,:),W,app.scomp,  app.normalize); 
                        ychannelsel =  feature_select_test(ychannels',Point3,app.featsel);
                        if app. augment ==1
                            ychannels_bf = xdawn_spatial_filter_projection(ytarget_id2_bf(IndTest,:,:),W_bf,app.scomp,  app.normalize); 
                            ychannelsel_bf =  feature_select_test(ychannels_bf',Point3_bf,app.featsel);
                        end                   
                 elseif app.featset==3 || app.featset==5 || app.featset==7
                     ychannels = temporal_feature_test(ytarget_id2(IndTest,:,:),  app.normalize);
                     ychannelsel =  feature_select_test(ychannels',Point3,app.featsel);
                     if app. augment ==1
                         ychannels_bf = temporal_feature_test(ytarget_id2_bf(IndTest,:,:),  app.normalize);
                         ychannelsel_bf =  feature_select_test(ychannels_bf',Point3_bf,app.featsel);
                     end   
                 
                 end
                 
                fprintf("Number of test samples: %d..\n",size(ychannelsel,1)); 
 
                classlbls = unique(clabel);
                priors = hist((clabel),classlbls);
                priors = priors/sum(priors);
                visLabel=[visLabel;TstLabel1'];

                %% Bayesian 
                if app.clsfrBayes==1
                    [class, ~]=sf_process_algorithm(ychannelsel,size(ychannelsel,1),win,modelo_T,modelo_nonT,priors(1),priors(2));
                    finalclass = class';
                    if app.augment ==1
                        [class_bf, ~]=sf_process_algorithm(ychannelsel_bf,size(ychannelsel_bf,1),win_bf,modelo_T_bf,modelo_nonT_bf,priors(1),priors(2));
                        finalclass =  augmentedclass(class', class_bf');
                    end
                    automaticBayes=[automaticBayes;finalclass];
                end
                 
                 %% Shrinkage LDA
                 if app.clsfrsLDA ==1
                  %rLDA Classification by Fabien LOTTE
                     trainingData = [X clabel'];
                     testData = [ychannelsel TstLabel1'];
                     ldaParams = RLDAAuto_Train(trainingData);
                     predsLDA = LDA_Test(testData, ldaParams);
                     finalclass = predsLDA.classes;
                     if app.augment ==1    
                         trainingData_bf = [X_bf clabel_bf'];
                         testData_bf = [ychannelsel_bf TstLabel1'];
                         ldaParams_bf = RLDAAuto_Train(trainingData_bf);
                         predsLDA_bf = LDA_Test(testData_bf, ldaParams_bf);
                         finalclass =  augmentedclass(predsLDA.classes, predsLDA_bf.classes);
                     end
                     automaticsLDA=[automaticsLDA;finalclass];
                 end  
                 
                 %% SVM linear
                 if app.clsfrSVM ==1
                     % MVPA-Light toolbox
                    paramSVM = mv_get_hyperparameter('svm');
                    cfSVM = train_svm(paramSVM, X, clabel');
                    [predSVM,~] = test_svm(cfSVM,ychannelsel);
                    finalclass = predSVM;
                    if app.augment ==1
                    paramSVM_bf = mv_get_hyperparameter('svm');
                    cfSVM_bf = train_svm(paramSVM_bf, X_bf, clabel_bf');
                    [predSVM_bf,~] = test_svm(cfSVM_bf,ychannelsel_bf);
                    finalclass = augmentedclass(predSVM, predSVM_bf);
                    end
                     automaticSVM=[automaticSVM;finalclass];
                 end 

                 %% SVM rbf
                 if app.clsfrSVMrbf ==1      
                      % MVPA-Light toolbox
                     paramSVMrbf = mv_get_hyperparameter('svm');
                     paramSVMrbf.kernel = 'rbf'; 
                      cfSVMrbf = train_svm(paramSVMrbf, X, clabel');
                     [predSVMrbf,~] = test_svm(cfSVMrbf,ychannelsel);
                      finalclass = predSVMrbf;
                     if app.augment ==1
                         paramSVMrbf_bf = mv_get_hyperparameter('svm');
                         paramSVMrbf_bf.kernel = 'rbf'; 
                         cfSVMrbf_bf = train_svm(paramSVMrbf_bf, X_bf, clabel_bf');
                         [predSVMrbf_bf,~] = test_svm(cfSVMrbf_bf,ychannelsel_bf);
                         finalclass = augmentedclass(predSVMrbf, predSVMrbf_bf);
                     end
                     automaticSVMrbf=[automaticSVMrbf;finalclass];
                 end 
             
            end  

            %% calculate performances of each classifier
            if app.clsfrBayes~=0
                disp('Bayes: ')
                [~,rest2_Bayes, TN_f,TP_f,FN_f,FP_f]  = performance(visLabel',automaticBayes',1,2,1);
                acc_all(p) = ((TN_f+TP_f)/(TN_f+TP_f+FN_f+FP_f))*100;
                spec_all(p) = (TN_f/(TN_f+FP_f))*100;
                sens_all(p) = (TP_f/(TP_f+FN_f))*100;
                bacc_all(p) = ((TN_f/(TN_f+FP_f))*100 + (TP_f/(TP_f+FN_f))*100)/2;
                if bacc_all(p)==rest2_Bayes(3,2)
                    disp(bacc_all)
                end
                
                tb_Bayes=[p, acc_all(p), sens_all(p), spec_all(p), bacc_all(p)];
                app.UITable_Bayes.ColumnName = {'SubjectID', 'Accuracy', 'Sensitivity', 'Specificity', 'Bal. Accuracy'};
                app.UITable_Bayes.ColumnEditable = false; 
                data_Bayes=get(app.UITable_Bayes, 'data'); % Get data as a string
                data_Bayes(end+1,:)=tb_Bayes; % Add new row of 0's as a cell type
                set(app.UITable_Bayes, 'data', data_Bayes);
                
                if app.totalsubjects>1
                    app.total_Bayes = app.total_Bayes + tb_Bayes; 
                end
            end

            if app.clsfrsLDA~=0
                [~,rest2_sLDA, TN_f,TP_f,FN_f,FP_f]  = performance(visLabel',automaticsLDA',1,2,1);
                disp('Shrinkage LDA: ')
                acc_all_sLDA(p) = ((TN_f+TP_f)/(TN_f+TP_f+FN_f+FP_f))*100;
                spec_all_sLDA(p) = (TN_f/(TN_f+FP_f))*100;
                sens_all_sLDA(p) = (TP_f/(TP_f+FN_f))*100;
                bacc_all_sLDA(p) = ((TN_f/(TN_f+FP_f))*100 + (TP_f/(TP_f+FN_f))*100)/2;   
                if bacc_all_sLDA(p)==rest2_sLDA(3,2)
                    disp(bacc_all_sLDA)
                end
                
                tb_sLDA=[p, acc_all_sLDA(p), sens_all_sLDA(p), spec_all_sLDA(p), bacc_all_sLDA(p)];
                app.UITable_sLDA.ColumnName = {'SubjectID', 'Accuracy', 'Sensitivity', 'Specificity', 'Bal. Accuracy'};
                app.UITable_sLDA.ColumnEditable = false; 
                data_sLDA=get(app.UITable_sLDA, 'data'); % Get data as a string
                data_sLDA(end+1,:)=tb_sLDA; % Add new row of 0's as a cell type
                set(app.UITable_sLDA, 'data', data_sLDA); 
                
                if app.totalsubjects>1
                    app.total_sLDA = app.total_sLDA + tb_sLDA;
                end
            end
            
            if app.clsfrSVM~=0
                [~,rest2_SVM, TN_f,TP_f,FN_f,FP_f]  = performance(visLabel',automaticSVM',1,2,1);
                disp('SVM: ')
                acc_all_SVM(p) = ((TN_f+TP_f)/(TN_f+TP_f+FN_f+FP_f))*100;
                spec_all_SVM(p) = (TN_f/(TN_f+FP_f))*100;
                sens_all_SVM(p) = (TP_f/(TP_f+FN_f))*100;
                bacc_all_SVM(p) = ((TN_f/(TN_f+FP_f))*100 + (TP_f/(TP_f+FN_f))*100)/2;   
                if bacc_all_SVM(p)==rest2_SVM(3,2)
                    disp(bacc_all_SVM)
                end
                
                tb_SVM=[p, acc_all_SVM(p), sens_all_SVM(p), spec_all_SVM(p), bacc_all_SVM(p)];
                app.UITable_SVM.ColumnName = {'SubjectID', 'Accuracy', 'Sensitivity', 'Specificity', 'Bal. Accuracy'};
                app.UITable_SVM.ColumnEditable = false; 
                data_SVM=get(app.UITable_SVM, 'data'); % Get data as a string
                data_SVM(end+1,:)=tb_SVM; % Add new row of 0's as a cell type
                set(app.UITable_SVM, 'data', data_SVM);
                
                if app.totalsubjects>1
                    app.total_SVM = app.total_SVM + tb_SVM;
                end
            end
            
            if app.clsfrSVMrbf~=0
                [~,rest2_SVMrbf, TN_f,TP_f,FN_f,FP_f]  = performance(visLabel',automaticSVMrbf',1,2,1);
                disp('SVM rbf: ')
                acc_all_SVMrbf(p) = ((TN_f+TP_f)/(TN_f+TP_f+FN_f+FP_f))*100;
                spec_all_SVMrbf(p) = (TN_f/(TN_f+FP_f))*100;
                sens_all_SVMrbf(p) = (TP_f/(TP_f+FN_f))*100;
                bacc_all_SVMrbf(p) = ((TN_f/(TN_f+FP_f))*100 + (TP_f/(TP_f+FN_f))*100)/2;   
                if bacc_all_SVMrbf(p)==rest2_SVMrbf(3,2)
                    disp(bacc_all_SVMrbf)
                end
                
                tb_SVMrbf=[p, acc_all_SVMrbf(p), sens_all_SVMrbf(p), spec_all_SVMrbf(p), bacc_all_SVMrbf(p)];
                app.UITable_SVMrbf.ColumnName = {'SubjectID', 'Accuracy', 'Sensitivity', 'Specificity', 'Bal. Accuracy'};
                app.UITable_SVMrbf.ColumnEditable = false; 
                data_SVMrbf=get(app.UITable_SVMrbf, 'data'); % Get data as a string
                data_SVMrbf(end+1,:)=tb_SVMrbf; % Add new row of 0's as a cell type
                set(app.UITable_SVMrbf, 'data', data_SVMrbf);
                
                if app.totalsubjects>1
                    app.total_SVMrbf = app.total_SVMrbf + tb_SVMrbf;
                end
            end

           
            if p==app.totalsubjects && app.totalsubjects>1
                avg_Bayes = app.total_Bayes/app.totalsubjects;
                avg_Bayes(1) = 0;
                data_Bayes=get(app.UITable_Bayes, 'data'); % Get data as a string
                data_Bayes(end+1,:)=avg_Bayes; % Add new row of 0's as a cell type
                set(app.UITable_Bayes, 'data', data_Bayes); 
                
                avg_sLDA = app.total_sLDA/app.totalsubjects;
                avg_sLDA(1) = 0;
                data_sLDA=get(app.UITable_sLDA, 'data'); % Get data as a string
                data_sLDA(end+1,:)=avg_sLDA; % Add new row of 0's as a cell type
                set(app.UITable_sLDA, 'data', data_sLDA);
                
                avg_SVM = app.total_SVM/app.totalsubjects;
                avg_SVM(1) = 0;
                data_SVM=get(app.UITable_SVM, 'data'); % Get data as a string
                data_SVM(end+1,:)=avg_SVM; % Add new row of 0's as a cell type
                set(app.UITable_SVM, 'data', data_SVM);
                
                avg_SVMrbf = app.total_SVMrbf/app.totalsubjects;
                avg_SVMrbf(1) = 0;
                data_SVMrbf=get(app.UITable_SVMrbf, 'data'); % Get data as a string
                data_SVMrbf(end+1,:)=avg_SVMrbf; % Add new row of 0's as a cell type
                set(app.UITable_SVMrbf, 'data', data_SVMrbf);
                            
     
                if ~exist([pwd,'\AllResults'], 'dir')               
                mkdir 'AllResults\LSC Speller'
                mkdir 'AllResults\BNCI Moving Cursor'
                mkdir 'AllResults\Kaggle P300 Speller'
                mkdir 'AllResults\HRI (cursor)'
                mkdir 'AllResults\HRI (robot)'
                mkdir 'AllResults\Coadaptation'
                mkdir 'AllResults\Gaming Agent'
                mkdir 'AllResults\Gaze Speller'
                mkdir 'AllResults\Tactile Feedback (V)'
                mkdir 'AllResults\Tactile Feedback (VT)'
                mkdir 'AllResults\Virtual Cursor'
                end
                tdata= datetime('now','Format','yyyy-MM-dd''T''HHmmss');  
                save(['AllResults\',app.datasetName,'\CAR',num2str(app.car),'_BP',num2str(app.lf),'-',num2str(app.hf),'_DS',num2str(app.downsamp),'_tw',num2str(app.EditFieldStart.Value),'-',num2str(app.EditFieldEnd.Value),'_featset',num2str(app.featset), '_norm',num2str(app.normalize),'_sel',num2str(app.featsel_method),'_',num2str(app.nf),'_aug',num2str(app.augment),'_results',char(tdata),'.mat'],'data_Bayes', 'data_sLDA', 'data_SVM', 'data_SVMrbf');        
            end         
        end
   

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

           initializeParams(app);
           DatasetDropDownValueChanged(app);
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            app.datasetName = app.DatasetDropDown.Value;
            disp(['Dataset (', app.datasetName,')',' selected!']);
           if app.datasetName=="BNCI Moving Cursor"
               app.datasetNo = 1;
               if app.downsamp==0
                   app.fs=512;     
               else
                   app.fs=64;  
               end
               app.nofchannels = 64;
               app.trialstart=0;
               app.trialend=1; 
              app.selwin= [0.2 0.7]; 
              app.test_exist = 1;
               app.scomp=4;
               app.channels = ones(1,64);
               app.DescriptionTextArea.Value = "Learning From EEG Error-Related Potentials in Noninvasive Brain-Computer Interfaces"; 
               app.Hyperlink.URL = "https://doi.org/10.1109/TNSRE.2010.2053387";                
            elseif app.datasetName=="Kaggle P300 Speller" 
               app.datasetNo = 2;
               if app.downsamp==0
                   app.fs=200;    %600 in the paper but the data has 200   
               else
                   app.fs=100;  
               end
               app.nofchannels = 32; % 56; % there are 56 channels in the dataset but  32 used in the paper
               app.trialstart=0;
               app.trialend=1.3; 
               app.selwin=  [0.3 0.8]; 
               app.test_exist = 1;
               app.scomp=4;
               app.channels = ones(1,32);
               app.DescriptionTextArea.Value = "Objective and Subjective Evaluation of Online Error Correction during P300-Based Spelling"; 
               app.Hyperlink.URL = "https://www.hindawi.com/journals/ahci/2012/578295/"; 
           elseif app.datasetName=="LSC Speller"
               app.datasetNo = 3;
               app.fs=256;
               app.nofchannels = 12;
               app.trialstart=0;
               app.trialend=1; 
               app.selwin= [0.2 0.7]; 
               app.test_exist = 1;
               app.scomp=2;
               app.channels = ones(1,12);
               app.DescriptionTextArea.Value = "Double ErrP Detection for Automatic Error Correction in an ERP-Based BCI Speller"; 
               app.Hyperlink.URL = "https://ieeexplore.ieee.org/document/8048036";                  
             elseif app.datasetName=="HRI (cursor)"
               app.datasetNo = 4;
               app.fs=256;
               app.nofchannels = 27;
               app.trialstart=0; %0.1
               app.trialend=1.2; %0.6
               app.selwin= [0 0.5]; 
               app.test_exist = 0;
               app.scomp=4;
               app.channels = ones(1,27);
               app.DescriptionTextArea.Value = "A Feasibility Study for Validating Robot Actions Using EEG-Based Error-Related Potentials"; 
               app.Hyperlink.URL = "https://doi.org/10.1007/s12369-018-0501-8";
            elseif app.datasetName=="HRI (robot)"
               app.datasetNo = 5;
               app.fs=256;
               app.nofchannels = 27;
               app.trialstart=0; %0.1
               app.trialend=1.2; %0.6
               app.selwin= [0 0.5]; 
               app.test_exist = 0;
               app.scomp=4;
               app.channels = ones(1,27);
               app.DescriptionTextArea.Value = "A Feasibility Study for Validating Robot Actions Using EEG-Based Error-Related Potentials"; 
               app.Hyperlink.URL = "https://doi.org/10.1007/s12369-018-0501-8";
             elseif app.datasetName=="Coadaptation"
               app.datasetNo = 6;
               app.fs=256;
               app.nofchannels = 27;
               app.trialstart= 0; %0.15;
               app.trialend=1; %0.55;
               app.selwin=[0.2 0.7]; 
               app.test_exist = 1;
               app.scomp=4;
               app.channels = ones(1,27);
               app.DescriptionTextArea.Value = "Human-agent co-adaptation using error-related potentials"; 
               app.Hyperlink.URL = "https://doi.org/10.1088/1741-2552/aae069";
             elseif app.datasetName=="Gaming Agent"
               app.datasetNo = 7;
               app.fs=250;
               app.nofchannels = 8;
               app.trialstart=0;
               app.trialend=2;
               app.selwin=  [0.2 0.7]; 
               app.test_exist = 0;
               app.scomp=2;
               app.channels = ones(1,8);
               app.DescriptionTextArea.Value = "Training a Gaming Agent on Brainwaves"; 
               app.Hyperlink.URL = "https://doi.org/10.1109/TG.2020.3042900";
            elseif app.datasetName=="Gaze Speller"
               app.datasetNo = 8;
               app.fs=256;
               app.nofchannels = 61;
               app.trialstart=0;
               app.trialend=0.5;
               app.selwin= [0.1 0.5]; 
               app.test_exist = 0;
               app.scomp=4;
               app.channels = ones(1,61);
               app.DescriptionTextArea.Value = "An error-aware gaze-based keyboard by means of a hybrid BCI system"; 
               app.Hyperlink.URL = "https://doi.org/10.1038/s41598-018-31425-2";
               elseif app.datasetName== "Tactile Feedback (V)"
               app.datasetNo = 9;
               app.fs=256;
               app.nofchannels = 16;
               app.trialstart=0;
               app.trialend=1;
               app.selwin=  [0.1 0.6]; 
               app.test_exist = 0;
               app.scomp=2;
               app.channels = ones(1,16);
               app.DescriptionTextArea.Value = "Does Tactile Feedback Enhance Single-Trial Detection of Error-Related EEG Potentials?"; 
               app.Hyperlink.URL = "https://doi.org/10.1109/SMC.2017.8122812";  
               elseif app.datasetName== "Tactile Feedback (VT)"
               app.datasetNo = 10;
               app.fs=256;
               app.nofchannels = 16;
               app.trialstart=0;
               app.trialend=1;
               app.selwin=  [0.1 0.6]; 
               app.test_exist = 0;
               app.scomp=2;
               app.channels = ones(1,16);
               app.DescriptionTextArea.Value = "Does Tactile Feedback Enhance Single-Trial Detection of Error-Related EEG Potentials?"; 
               app.Hyperlink.URL = "https://doi.org/10.1109/SMC.2017.8122812";
               elseif app.datasetName== "Virtual Cursor"
               app.datasetNo = 11;
               app.fs=256;
               app.nofchannels = 16;
               app.trialstart=0;
               app.trialend=1;
               app.selwin= [0.3 0.8]; 
               app.test_exist = 0;
               app.scomp=2;
               app.channels = ones(1,16);
               app.DescriptionTextArea.Value = "Exploiting task constraints for self-calibrated brain-machine interface control using error-related potentials"; 
               app.Hyperlink.URL = "https://doi.org/10.1371/journal.pone.0131491";   
            end           
            initializeParams(app);
            gettotalsubjects(app);
            subjectids = ["1", "2", "3", "4","5", "6", "7", "8", "9","10", "11", "12", "13", "14", "15", "16", "17", "18", "19", "20", "21", "22","23" ,"24", "25", "26"];
            app.SubjectDropDown.Items = ["All", subjectids(1:app.totalsubjects)];
            SubjectDropDownValueChanged(app);

        end

        % Button pushed function: PlotChannelsButton
        function PlotChannelsButtonPushed(app, event)
            app.FzCheckBox.Value = 1;
            app.CzCheckBox.Value = 1;
            app.C3CheckBox.Value = 1;
            app.C4CheckBox.Value = 1;
            app.CPzCheckBox.Value = 1;
            app.PzCheckBox.Value = 1;
            app.P3CheckBox.Value = 1;
            app.P4CheckBox.Value = 1;
            if app.nofchannels>8
                app.PO7CheckBox.Value = 1;
                app.PO8CheckBox.Value = 1;
                app.POzCheckBox.Value = 1;
                app.OzCheckBox.Value = 1;      
            else
                app.PO7CheckBox.Value = 0;
                app.PO8CheckBox.Value = 0;
                app.POzCheckBox.Value = 0;
                app.OzCheckBox.Value = 0; 
                
            end 
            close all
            if app.subjectid == 0
                loadAllSubjectsData(app);
            end
            plotchndata(app);
            %plotshadedaverage(app);
            plotrsquare(app);
            plotacrosstime(app);
            %ExportButtonPushed(app);
            
        end

        % Value changed function: FeaturesListBox
        function FeaturesListBoxValueChanged(app, event)
             sfilt = app.FeaturesListBox.Value;
            disp([sfilt,' features selected!']);            
            if sfilt == "FCB"
                app.featset=1;                
            elseif sfilt == "xDAWN"
                app.featset=2;                
            elseif sfilt == "Overlapped window averages"
                app.featset=3;                
             elseif sfilt == "FCz and Cz"
                app.featset=5;    
             elseif sfilt == "Time points (All channels)"
                app.featset=7;            
            end
        end

        % Value changed function: FzCheckBox
        function FzCheckBoxValueChanged(app, event)
            app.channels(1) = app.FzCheckBox.Value;
            plotchndata(app);
        end

        % Value changed function: CzCheckBox
        function CzCheckBoxValueChanged(app, event)
            app.channels(2) = app.CzCheckBox.Value;
            plotchndata(app);
        end

        % Value changed function: C3CheckBox
        function C3CheckBoxValueChanged(app, event)
            app.channels(3) = app.C3CheckBox.Value;
            plotchndata(app);
        end

        % Value changed function: C4CheckBox
        function C4CheckBoxValueChanged(app, event)
            app.channels(4) = app.C4CheckBox.Value;
            plotchndata(app);
        end

        % Value changed function: CPzCheckBox
        function CPzCheckBoxValueChanged(app, event)
            app.channels(5) = app.CPzCheckBox.Value;
            plotchndata(app);
        end

        % Value changed function: PzCheckBox
        function PzCheckBoxValueChanged(app, event)
            app.channels(6) = app.PzCheckBox.Value;
            plotchndata(app);
        end

        % Value changed function: P3CheckBox
        function P3CheckBoxValueChanged(app, event)
            app.channels(7) = app.P3CheckBox.Value;
            plotchndata(app);
        end

        % Value changed function: P4CheckBox
        function P4CheckBoxValueChanged(app, event)
            app.channels(8) = app.P4CheckBox.Value;
            plotchndata(app);
        end

        % Value changed function: PO7CheckBox
        function PO7CheckBoxValueChanged(app, event)
            if app.nofchannels > 8
                app.channels(9) = app.PO7CheckBox.Value;
                plotchndata(app);
            end
        end

        % Value changed function: PO8CheckBox
        function PO8CheckBoxValueChanged(app, event)
            if app.nofchannels > 8
                app.channels(10) = app.PO8CheckBox.Value;
                plotchndata(app);
            end
        end

        % Value changed function: POzCheckBox
        function POzCheckBoxValueChanged(app, event)
            if app.nofchannels > 8
                app.channels(11) = app.POzCheckBox.Value;
                plotchndata(app);
            end
        end

        % Value changed function: OzCheckBox
        function OzCheckBoxValueChanged(app, event)
            if app.nofchannels > 8
                app.channels(12) = app.OzCheckBox.Value;
                plotchndata(app);
            end
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if ~exist([pwd,'\AllFigures'], 'dir')               
                mkdir 'AllFigures\LSC Speller'
                mkdir 'AllFigures\BNCI Moving Cursor'
                mkdir 'AllFigures\Kaggle P300 Speller'
                mkdir 'AllFigures\HRI (cursor)'
                mkdir 'AllFigures\HRI (robot)'
                mkdir 'AllFigures\Coadaptation'
                mkdir 'AllFigures\Gaming Agent'
                mkdir 'AllFigures\Gaze Speller'
                mkdir 'AllFigures\Tactile Feedback (V)'
                mkdir 'AllFigures\Tactile Feedback (VT)'
                mkdir 'AllFigures\Virtual Cursor'
            end
           
                exportgraphics(app.UIAxesAverage,strcat(pwd,'\AllFigures\',app.datasetName,'\S',num2str(app.subjectid),'_PlotAverage.png'))
                exportgraphics(app.UIAxesDifference,strcat(pwd,'\AllFigures\',app.datasetName,'\S',num2str(app.subjectid),'_PlotDifference.png'))
                exportgraphics(app.UIAxesRsquare,strcat(pwd,'\AllFigures\',app.datasetName,'\S',num2str(app.subjectid),'_PlotRsquare.png'))
                exportgraphics(app.UIAxesAUC,strcat(pwd,'\AllFigures\',app.datasetName,'\S',num2str(app.subjectid),'_PlotAUC.png'))
                writecell(app.noftrials,strcat(pwd,'\AllFigures\',app.datasetName,'\S',num2str(app.subjectid),'_noftrials.txt'));
           
        end

        % Button pushed function: PreprocessButton
        function PreprocessButtonPushed(app, event)
            % Disable Parameters button while dialog is open
            app.PreprocessButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp = DialogParams(app, app.datasetName, app.car, app.lf, app.hf, app.downsamp);
        end

        % Value changed function: SubjectDropDown
        function SubjectDropDownValueChanged(app, event)
            app.CorrectLabel.Text = "";
            app.ErrorLabel.Text = "";
            if app.SubjectDropDown.Value == "All"
                app.subjectid  = 0;
                disp(['All subjects selected!']);
               % loadAllSubjectsData(app);
                app.FilenameEditField.Value = ['fig_S', num2str(app.subjectid)];
                zerrors = app.XtrainAll(:,:,app.YtrainAll==1);
                znonerrors = app.XtrainAll(:,:,app.YtrainAll==2);
            else
                app.subjectid  = str2num(app.SubjectDropDown.Value);
                disp(['Subject ', app.SubjectDropDown.Value,' selected!']);
                loadSubjectData(app,app.subjectid);
                app.FilenameEditField.Value = ['fig_S', num2str(app.subjectid)];
                zerrors = app.Xtrain(:,:,app.Ytrain==1);
                znonerrors = app.Xtrain(:,:,app.Ytrain==2);
            end 
                app.ErrorLabel.Text = [num2str(size(zerrors,3)),' errors'];
                app.CorrectLabel.Text = [num2str(size(znonerrors,3)),' corrects'];
                app.noftrials = {'Error',size(zerrors,3);'Correct',size(znonerrors,3)};
                        
        end

        % Callback function
        function DatasetDropDownOpening(app, event)
            app.DescriptionTextArea.Value = "Loading dataset..."; 
        end

        % Callback function
        function ErrPAnalysisUIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
            
        end

        % Button pushed function: PlotFeaturesButton
        function PlotFeaturesButtonPushed(app, event)
             [ytrain_cne, ~, ~, ~] = extractepochs (app);

             if app.featset==1 % FCB filter
                fprintf("Plotting %d FCB spatial projections..\n",app.scomp); 
                 [zspatialTRAIN_t, zspatialTRAIN_nt, Uall, ~, totalnumfeat ] =  fcb_feature_extraction(ytrain_cne(:,:,app.Ytrain==1),ytrain_cne(:,:,app.Ytrain==2), app.nofchannels, app.scomp, app.normalize);
                  % plot features 
                p1=plot(app.UIAxesFCB,1:size(zspatialTRAIN_t,1),mean(zspatialTRAIN_t,2),'Color','r');
                hold(app.UIAxesFCB, 'on');
                p2=plot(app.UIAxesFCB,1:size(zspatialTRAIN_nt,1),mean(zspatialTRAIN_nt,2),'Color','b');
                hold(app.UIAxesFCB, 'off');
                h1= [p1(1); p2(1)];
                legend(app.UIAxesFCB,h1,{'error','correct'})
                
             elseif app.featset==2 % xDAWN filter
                fprintf("Plotting %d xDAWN spatial projections..\n",app.scomp); 
                [xdawnspatialTRAIN_t, xdawnspatialTRAIN_nt, W, totalnumfeat]= xdawn_feature_extraction(ytrain_cne,app.Ytrain,app.scomp, app.normalize); 
                     % plot features 
                p1=plot(app.UIAxesXDAWN,1:size(xdawnspatialTRAIN_t,1),mean(xdawnspatialTRAIN_t,2),'Color','r');
                hold(app.UIAxesXDAWN, 'on');
                p2=plot(app.UIAxesXDAWN,1:size(xdawnspatialTRAIN_nt,1),mean(xdawnspatialTRAIN_nt,2),'Color','b');
                hold(app.UIAxesXDAWN, 'off');
                h1= [p1(1); p2(1)];
                legend(app.UIAxesXDAWN,h1,{'error','correct'})

               elseif  app.featset==3  
                    fprintf("Plotting overlapped averages of %d windows from %d channels..\n",size(ytrain_cne,2),size(ytrain_cne,1)); 
                    [ztemporalTRAIN_t, ztemporalTRAIN_nt, totalnumfeat]= temporal_feature_extraction(ytrain_cne,app.Ytrain, app.normalize);
                    % plot features 
                    p1=plot(app.UIAxesOverlap,1:size(ztemporalTRAIN_t,1),mean(ztemporalTRAIN_t,2),'Color','r');
                    hold(app.UIAxesOverlap, 'on');
                    p2=plot(app.UIAxesOverlap,1:size(ztemporalTRAIN_nt,1),mean(ztemporalTRAIN_nt,2),'Color','b');
                    hold(app.UIAxesOverlap, 'off');
                    h1= [p1(1); p2(1)];
                    legend(app.UIAxesOverlap,h1,{'error','correct'})
               
             elseif  app.featset==5
                    fprintf("Plotting temporal features from %d (FCz and Cz) channels..\n",size(ytrain_cne,1)); 
                    [ztemporalTRAIN_t, ztemporalTRAIN_nt, totalnumfeat]= temporal_feature_extraction(ytrain_cne,app.Ytrain, app.normalize);
                    % plot features 
                    p1=plot(app.UIAxesFCzCz,1:size(ztemporalTRAIN_t,1),mean(ztemporalTRAIN_t,2),'Color','r');
                    hold(app.UIAxesFCzCz, 'on');
                    p2=plot(app.UIAxesFCzCz,1:size(ztemporalTRAIN_nt,1),mean(ztemporalTRAIN_nt,2),'Color','b');
                    hold(app.UIAxesFCzCz, 'off');
                    h1= [p1(1); p2(1)];
                    legend(app.UIAxesFCzCz,h1,{'error','correct'})

              elseif  app.featset==7
                    fprintf("Plotting temporal features from %d channels..\n",size(ytrain_cne,1)); 
                    [ztemporalTRAIN_t, ztemporalTRAIN_nt, totalnumfeat]= temporal_feature_extraction(ytrain_cne,app.Ytrain, app.normalize);
                    % plot features 
                    p1=plot(app.UIAxesAllChannels,1:size(ztemporalTRAIN_t,1),mean(ztemporalTRAIN_t,2),'Color','r');
                    hold(app.UIAxesAllChannels, 'on');
                    p2=plot(app.UIAxesAllChannels,1:size(ztemporalTRAIN_nt,1),mean(ztemporalTRAIN_nt,2),'Color','b');
                    hold(app.UIAxesAllChannels, 'off');
                    h1= [p1(1); p2(1)];
                    legend(app.UIAxesAllChannels,h1,{'error','correct'})

              end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
           % app.RunButton.Enable = 'off'; 
            
            if app.subjectid ~= 0
               KFoldCrossValid(app, app.subjectid);
            else
                for i=1:app.totalsubjects
                    loadSubjectData(app,i);
                    KFoldCrossValid(app, i);
                end
            end
            %app.RunButton.Enable = 'on'; 
           % delete(app);  % only when it is called from main app
        end

        % Value changed function: BayesCheckBox
        function BayesCheckBoxValueChanged(app, event)
            app.clsfrBayes = app.BayesCheckBox.Value;
            
        end

        % Value changed function: sLDACheckBox
        function sLDACheckBoxValueChanged(app, event)
            app.clsfrsLDA = app.sLDACheckBox.Value;
            
        end

        % Value changed function: SVMCheckBox
        function SVMCheckBoxValueChanged(app, event)
            app.clsfrSVM = app.SVMCheckBox.Value;
            
        end

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            app.total_Bayes = 0;
            app.total_sLDA = 0;
            app.total_SVM = 0;
            app.total_SVMrbf = 0;
            set(app.UITable_Bayes, 'data', []);
            set(app.UITable_sLDA, 'data', []);
            set(app.UITable_SVM, 'data', []);
            set(app.UITable_SVMrbf, 'data', []);
          
        end

        % Value changed function: DropDownNcomp
        function DropDownNcompValueChanged(app, event)
            app.scomp = str2num(app.DropDownNcomp.Value);
        end

        % Value changed function: EditFieldNfeat
        function EditFieldNfeatValueChanged(app, event)
            app.nf = app.EditFieldNfeat.Value;
        end

        % Value changed function: EditFieldStart
        function EditFieldStartValueChanged(app, event)
            app.trialstart = app.EditFieldStart.Value;
        end

        % Value changed function: EditFieldEnd
        function EditFieldEndValueChanged(app, event)
            app.trialend = app.EditFieldEnd.Value;
        end

        % Callback function
        function LDACheckBoxValueChanged(app, event)
            app.clsfrLDA = app.LDACheckBox.Value;
            
        end

        % Callback function
        function GMMCheckBoxValueChanged(app, event)
            app.clsfrGMM  = app.GMMCheckBox.Value;
            
        end

        % Callback function
        function GNBCheckBoxValueChanged(app, event)
            app.clsfrGNB = app.GNBCheckBox.Value;
            
        end

        % Value changed function: SVMrbfCheckBox
        function SVMrbfCheckBoxValueChanged(app, event)
            app.clsfrSVMrbf = app.SVMrbfCheckBox.Value;
            
        end

        % Callback function
        function CARCheckBox_2ValueChanged(app, event)
           
            
        end

        % Callback function
        function ApplyButtonPushed(app, event)
            loadSubjectData(app,app.subjectid);
           
        end

        % Callback function
        function CommonAverageReferenceCheckBoxValueChanged(app, event)
            app.car = app.CommonAverageReferenceCheckBox.Value;
           ApplyButtonPushed(app);
        end

        % Callback function
        function DownsamplingCheckBoxValueChanged(app, event)
            app.downsamp = app.DownsamplingCheckBox.Value;
            ApplyButtonPushed(app);
        end

        % Value changed function: NormalizefeaturesCheckBox
        function NormalizefeaturesCheckBoxValueChanged(app, event)
            app.normalize = app.NormalizefeaturesCheckBox.Value;
            
            
        end

        % Callback function
        function EditFieldLFValueChanged(app, event)
            app.lf = app.EditFieldLF.Value;
            ApplyButtonPushed(app);
        end

        % Callback function
        function EditFieldHFValueChanged(app, event)
            app.hf = app.EditFieldHF.Value;
            ApplyButtonPushed(app);
        end

        % Callback function
        function AugmentButtonPushed(app, event)
            
        end

        % Value changed function: AugmentCheckBox
        function AugmentCheckBoxValueChanged(app, event)
            app.augment = app.AugmentCheckBox.Value;
            
        end

        % Value changed function: SelectfeaturesCheckBox
        function SelectfeaturesCheckBoxValueChanged(app, event)
            app.featsel = app.SelectfeaturesCheckBox.Value;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            legend( app.UIAxesFCB, 'off');
            legend( app.UIAxesXDAWN, 'off');
            legend( app.UIAxesOverlap, 'off');
            legend( app.UIAxesFCzCz, 'off');
            legend( app.UIAxesAllChannels, 'off');
            cla(app.UIAxesFCB)
            cla(app.UIAxesXDAWN)
            cla(app.UIAxesOverlap)
            cla(app.UIAxesFCzCz)
            cla(app.UIAxesAllChannels)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ErrPAnalysisUIFigure = uifigure('Visible', 'off');
            app.ErrPAnalysisUIFigure.Name = 'ErrP Analysis';
            app.ClassificationPanel = uipanel(app.ErrPAnalysisUIFigure);
            app.ClassificationPanel.Title = 'Classification';
            app.TabGroup2 = uitabgroup(app.ClassificationPanel);
            app.BayesTab = uitab(app.TabGroup2);
            app.BayesTab.Title = 'Bayes';
            app.UITable_Bayes = uitable(app.BayesTab);
            app.UITable_Bayes.ColumnName = {''; ''; ''; ''; ''};
            app.UITable_Bayes.RowName = {};
            app.sLDATab = uitab(app.TabGroup2);
            app.sLDATab.Title = 'sLDA';
            app.UITable_sLDA = uitable(app.sLDATab);
            app.UITable_sLDA.ColumnName = {''; ''; ''; ''; ''};
            app.UITable_sLDA.RowName = {};
            app.SVMTab = uitab(app.TabGroup2);
            app.SVMTab.Title = 'SVM';
            app.UITable_SVM = uitable(app.SVMTab);
            app.UITable_SVM.ColumnName = {''; ''; ''; ''; ''};
            app.UITable_SVM.RowName = {};
            app.SVMrbfTab = uitab(app.TabGroup2);
            app.SVMrbfTab.Title = 'SVMrbf';
            app.UITable_SVMrbf = uitable(app.SVMrbfTab);
            app.UITable_SVMrbf.ColumnName = {''; ''; ''; ''; ''};
            app.UITable_SVMrbf.RowName = {};
            app.RunButton = uibutton(app.ClassificationPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.BayesCheckBox = uicheckbox(app.ClassificationPanel);
            app.BayesCheckBox.ValueChangedFcn = createCallbackFcn(app, @BayesCheckBoxValueChanged, true);
            app.BayesCheckBox.Text = 'Bayes';
            app.BayesCheckBox.Value = true;
            app.sLDACheckBox = uicheckbox(app.ClassificationPanel);
            app.sLDACheckBox.ValueChangedFcn = createCallbackFcn(app, @sLDACheckBoxValueChanged, true);
            app.sLDACheckBox.Text = 'sLDA';
            app.sLDACheckBox.Value = true;
            app.SVMCheckBox = uicheckbox(app.ClassificationPanel);
            app.SVMCheckBox.ValueChangedFcn = createCallbackFcn(app, @SVMCheckBoxValueChanged, true);
            app.SVMCheckBox.Text = 'SVM';
            app.SVMCheckBox.Value = true;
            app.SVMrbfCheckBox = uicheckbox(app.ClassificationPanel);
            app.SVMrbfCheckBox.ValueChangedFcn = createCallbackFcn(app, @SVMrbfCheckBoxValueChanged, true);
            app.SVMrbfCheckBox.Text = 'SVMrbf';
            app.SVMrbfCheckBox.Value = true;
            app.ClearTableButton = uibutton(app.ClassificationPanel, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Text = 'Clear Table';
            app.DatasetDropDownLabel = uilabel(app.ErrPAnalysisUIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.ErrPAnalysisUIFigure);
            app.DatasetDropDown.Items = {'BNCI Moving Cursor', 'Kaggle P300 Speller', 'LSC Speller', 'HRI (cursor)', 'HRI (robot)', 'Coadaptation', 'Gaming Agent', 'Gaze Speller', 'Tactile Feedback (V)', 'Tactile Feedback (VT)', 'Virtual Cursor'};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = 'LSC Speller';
            app.FilenameEditFieldLabel = uilabel(app.ErrPAnalysisUIFigure);
            app.FilenameEditFieldLabel.Text = 'File name:';
            app.FilenameEditField = uieditfield(app.ErrPAnalysisUIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.FilenameEditField.Value = 'fig1';
            app.PlotChannelsButton = uibutton(app.ErrPAnalysisUIFigure, 'push');
            app.PlotChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotChannelsButtonPushed, true);
            app.PlotChannelsButton.Text = 'Plot Channels';
            app.FzCheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.FzCheckBox.ValueChangedFcn = createCallbackFcn(app, @FzCheckBoxValueChanged, true);
            app.FzCheckBox.Text = 'Fz';
            app.FzCheckBox.Value = true;
            app.CzCheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.CzCheckBox.ValueChangedFcn = createCallbackFcn(app, @CzCheckBoxValueChanged, true);
            app.CzCheckBox.Text = 'Cz';
            app.CzCheckBox.Value = true;
            app.C3CheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.C3CheckBox.ValueChangedFcn = createCallbackFcn(app, @C3CheckBoxValueChanged, true);
            app.C3CheckBox.Text = 'C3';
            app.C3CheckBox.Value = true;
            app.C4CheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.C4CheckBox.ValueChangedFcn = createCallbackFcn(app, @C4CheckBoxValueChanged, true);
            app.C4CheckBox.Text = 'C4';
            app.C4CheckBox.Value = true;
            app.CPzCheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.CPzCheckBox.ValueChangedFcn = createCallbackFcn(app, @CPzCheckBoxValueChanged, true);
            app.CPzCheckBox.Text = 'CPz';
            app.CPzCheckBox.Value = true;
            app.PzCheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.PzCheckBox.ValueChangedFcn = createCallbackFcn(app, @PzCheckBoxValueChanged, true);
            app.PzCheckBox.Text = 'Pz';
            app.PzCheckBox.Value = true;
            app.P3CheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.P3CheckBox.ValueChangedFcn = createCallbackFcn(app, @P3CheckBoxValueChanged, true);
            app.P3CheckBox.Text = 'P3';
            app.P3CheckBox.Value = true;
            app.P4CheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.P4CheckBox.ValueChangedFcn = createCallbackFcn(app, @P4CheckBoxValueChanged, true);
            app.P4CheckBox.Text = 'P4';
            app.P4CheckBox.Value = true;
            app.PO7CheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.PO7CheckBox.ValueChangedFcn = createCallbackFcn(app, @PO7CheckBoxValueChanged, true);
            app.PO7CheckBox.Text = 'PO7';
            app.PO7CheckBox.Value = true;
            app.PO8CheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.PO8CheckBox.ValueChangedFcn = createCallbackFcn(app, @PO8CheckBoxValueChanged, true);
            app.PO8CheckBox.Text = 'PO8';
            app.PO8CheckBox.Value = true;
            app.POzCheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.POzCheckBox.ValueChangedFcn = createCallbackFcn(app, @POzCheckBoxValueChanged, true);
            app.POzCheckBox.Text = 'POz';
            app.POzCheckBox.Value = true;
            app.OzCheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.OzCheckBox.ValueChangedFcn = createCallbackFcn(app, @OzCheckBoxValueChanged, true);
            app.OzCheckBox.Text = 'Oz';
            app.OzCheckBox.Value = true;
            app.FpzCheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.FpzCheckBox.Text = 'Fpz';
            app.FCzCheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.FCzCheckBox.Text = 'FCz';
            app.FC1CheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.FC1CheckBox.Text = 'FC1';
            app.FC2CheckBox = uicheckbox(app.ErrPAnalysisUIFigure);
            app.FC2CheckBox.Text = 'FC2';
            app.DescriptionLabel = uilabel(app.ErrPAnalysisUIFigure);
            app.DescriptionLabel.Text = 'Description: ';
            app.DescriptionTextArea = uitextarea(app.ErrPAnalysisUIFigure);
            app.ExportButton = uibutton(app.ErrPAnalysisUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SubjectDropDownLabel = uilabel(app.ErrPAnalysisUIFigure);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.ErrPAnalysisUIFigure);
            app.SubjectDropDown.Items = {'All', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.SubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.SubjectDropDown.Value = '1';
            app.TabGroup3 = uitabgroup(app.ErrPAnalysisUIFigure);
            app.AverageTab = uitab(app.TabGroup3);
            app.AverageTab.Title = 'Average';
            app.UIAxesAverage = uiaxes(app.AverageTab);
            xlabel(app.UIAxesAverage, 'time')
            ylabel(app.UIAxesAverage, 'amplitude')
            app.UIAxesAverage.XLim = [0 1];
            app.UIAxesAverage.XTick = [-0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesAverage.XTickLabel = {'-0.2'; '-0.1'; '0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxesAverage.YTick = [-20 -15 -10 -5 0 5 10 15 20];
            app.UIAxesAverage.YTickLabel = {'-20'; '-15'; '-10'; '-5'; '0'; '5'; '10'; '15'; '20'};
            app.errorcorrectTab = uitab(app.TabGroup3);
            app.errorcorrectTab.Title = 'error-correct';
            app.UIAxesDifference = uiaxes(app.errorcorrectTab);
            xlabel(app.UIAxesDifference, 'time')
            ylabel(app.UIAxesDifference, 'amplitude')
            app.UIAxesDifference.XLim = [0 1];
            app.UIAxesDifference.XTick = [-0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesDifference.XTickLabel = {'-0.2'; '-0.1'; '0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxesDifference.YTick = [-20 -15 -10 -5 0 5 10 15 20];
            app.UIAxesDifference.YTickLabel = {'-20'; '-15'; '-10'; '-5'; '0'; '5'; '10'; '15'; '20'};
            app.RsquareTab = uitab(app.TabGroup3);
            app.RsquareTab.Title = 'Rsquare';
            app.UIAxesRsquare = uiaxes(app.RsquareTab);
            app.UIAxesRsquare.XLim = [0 1];
            app.UIAxesRsquare.XTick = [-0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesRsquare.XTickLabel = {'-0.2'; '-0.1'; '0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxesRsquare.YTick = [-20 -15 -10 -5 0 5 10 15 20];
            app.UIAxesRsquare.YTickLabel = {'-20'; '-15'; '-10'; '-5'; '0'; '5'; '10'; '15'; '20'};
            app.AUCTab = uitab(app.TabGroup3);
            app.AUCTab.Title = 'AUC';
            app.UIAxesAUC = uiaxes(app.AUCTab);
            xlabel(app.UIAxesAUC, 'time')
            ylabel(app.UIAxesAUC, 'AUC')
            zlabel(app.UIAxesAUC, 'Z')
            app.SNRTab = uitab(app.TabGroup3);
            app.SNRTab.Title = 'SNR';
            app.UIAxesSNR = uiaxes(app.SNRTab);
            xlabel(app.UIAxesSNR, 'channels')
            zlabel(app.UIAxesSNR, 'Z')
            app.ErrorLabel = uilabel(app.ErrPAnalysisUIFigure);
            app.ErrorLabel.Text = '0';
            app.CorrectLabel = uilabel(app.ErrPAnalysisUIFigure);
            app.CorrectLabel.Text = '0';
            app.Hyperlink = uihyperlink(app.ErrPAnalysisUIFigure);
            app.Hyperlink.Text = 'Link';
            app.FeatureExtractionPanel = uipanel(app.ErrPAnalysisUIFigure);
            app.FeatureExtractionPanel.Title = 'Feature Extraction';
            app.TabGroup = uitabgroup(app.FeatureExtractionPanel);
            app.FCBTab = uitab(app.TabGroup);
            app.FCBTab.Title = 'FCB';
            app.UIAxesFCB = uiaxes(app.FCBTab);
            title(app.UIAxesFCB, 'FCB Spatial Projections')
            xlabel(app.UIAxesFCB, 'time')
            ylabel(app.UIAxesFCB, 'amplitude')
            zlabel(app.UIAxesFCB, 'Z')
            app.xDAWNTab = uitab(app.TabGroup);
            app.xDAWNTab.Title = 'xDAWN';
            app.UIAxesXDAWN = uiaxes(app.xDAWNTab);
            title(app.UIAxesXDAWN, 'xDAWN Spatial Projections')
            xlabel(app.UIAxesXDAWN, 'time')
            ylabel(app.UIAxesXDAWN, 'amplitude')
            zlabel(app.UIAxesXDAWN, 'Z')
            app.OverlapTab = uitab(app.TabGroup);
            app.OverlapTab.Title = 'Overlap';
            app.UIAxesOverlap = uiaxes(app.OverlapTab);
            title(app.UIAxesOverlap, 'Average of overlapped time windows')
            xlabel(app.UIAxesOverlap, 'time')
            ylabel(app.UIAxesOverlap, 'amplitude')
            zlabel(app.UIAxesOverlap, 'Z')
            app.FCzCzTab = uitab(app.TabGroup);
            app.FCzCzTab.Title = 'FCzCz';
            app.UIAxesFCzCz = uiaxes(app.FCzCzTab);
            title(app.UIAxesFCzCz, 'Time point features from FCz and Cz channels')
            xlabel(app.UIAxesFCzCz, 'time')
            ylabel(app.UIAxesFCzCz, 'amplitude')
            zlabel(app.UIAxesFCzCz, 'Z')
            app.TimepointsTab = uitab(app.TabGroup);
            app.TimepointsTab.Title = 'Timepoints';
            app.UIAxesAllChannels = uiaxes(app.TimepointsTab);
            title(app.UIAxesAllChannels, 'Time point features from all channels')
            xlabel(app.UIAxesAllChannels, 'time')
            ylabel(app.UIAxesAllChannels, 'amplitude')
            zlabel(app.UIAxesAllChannels, 'Z')
            app.FeaturesListBoxLabel = uilabel(app.FeatureExtractionPanel);
            app.FeaturesListBoxLabel.Text = 'Features';
            app.FeaturesListBox = uilistbox(app.FeatureExtractionPanel);
            app.FeaturesListBox.Items = {'FCB', 'xDAWN', 'Overlapped window averages', 'FCz and Cz', 'Time points (All channels)'};
            app.FeaturesListBox.ValueChangedFcn = createCallbackFcn(app, @FeaturesListBoxValueChanged, true);
            app.FeaturesListBox.Value = 'FCB';
            app.PlotFeaturesButton = uibutton(app.FeatureExtractionPanel, 'push');
            app.PlotFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFeaturesButtonPushed, true);
            app.PlotFeaturesButton.Text = 'Plot Features';
            app.NumberofFeaturesLabel = uilabel(app.FeatureExtractionPanel);
            app.NumberofFeaturesLabel.Text = 'Number of Features';
            app.EditFieldNfeat = uieditfield(app.FeatureExtractionPanel, 'numeric');
            app.EditFieldNfeat.Limits = [2 1000];
            app.EditFieldNfeat.ValueChangedFcn = createCallbackFcn(app, @EditFieldNfeatValueChanged, true);
            app.EditFieldNfeat.Value = 100;
            app.NumberofspatialprojectionsLabel = uilabel(app.FeatureExtractionPanel);
            app.NumberofspatialprojectionsLabel.Text = 'Number of spatial projections';
            app.DropDownNcomp = uidropdown(app.FeatureExtractionPanel);
            app.DropDownNcomp.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.DropDownNcomp.ValueChangedFcn = createCallbackFcn(app, @DropDownNcompValueChanged, true);
            app.DropDownNcomp.Value = '2';
            app.TimewindowstartLabel = uilabel(app.FeatureExtractionPanel);
            app.TimewindowstartLabel.Text = 'Time window:     start';
            app.EditFieldStart = uieditfield(app.FeatureExtractionPanel, 'numeric');
            app.EditFieldStart.Limits = [-1 1000];
            app.EditFieldStart.ValueChangedFcn = createCallbackFcn(app, @EditFieldStartValueChanged, true);
            app.endLabel = uilabel(app.FeatureExtractionPanel);
            app.endLabel.Text = 'end';
            app.EditFieldEnd = uieditfield(app.FeatureExtractionPanel, 'numeric');
            app.EditFieldEnd.Limits = [0 1000];
            app.EditFieldEnd.ValueChangedFcn = createCallbackFcn(app, @EditFieldEndValueChanged, true);
            app.EditFieldEnd.Value = 1;
            app.sLabel = uilabel(app.FeatureExtractionPanel);
            app.sLabel.Text = 's';
            app.NormalizefeaturesCheckBox = uicheckbox(app.FeatureExtractionPanel);
            app.NormalizefeaturesCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizefeaturesCheckBoxValueChanged, true);
            app.NormalizefeaturesCheckBox.Text = 'Normalize features';
            app.NormalizefeaturesCheckBox.Value = true;
            app.AugmentCheckBox = uicheckbox(app.FeatureExtractionPanel);
            app.AugmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @AugmentCheckBoxValueChanged, true);
            app.AugmentCheckBox.Text = 'Augment';
            app.SelectfeaturesCheckBox = uicheckbox(app.FeatureExtractionPanel);
            app.SelectfeaturesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectfeaturesCheckBoxValueChanged, true);
            app.SelectfeaturesCheckBox.Text = 'Select features';
            app.ClearButton = uibutton(app.FeatureExtractionPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear ';
            app.NumberoftrialsLabel = uilabel(app.ErrPAnalysisUIFigure);
            app.NumberoftrialsLabel.Text = 'Number of trials';
            app.PreprocessButton = uibutton(app.ErrPAnalysisUIFigure, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessButtonPushed, true);
            app.PreprocessButton.Text = 'Preprocess';
            % Show the figure after all components are created
            app.ErrPAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ErrP_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ErrPAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ErrPAnalysisUIFigure)
        end
    end
end